classdef createMask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        IndexSpinnerLabel  matlab.ui.control.Label
        IndexSpinner       matlab.ui.control.Spinner
        AxisButtonGroup    matlab.ui.container.ButtonGroup
        XYButton           matlab.ui.control.RadioButton
        XZButton           matlab.ui.control.RadioButton
        YZButton           matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        data % Description
        axis
        roi
    end
    
    methods (Access = private)
        
        function sliced_data = slice_data(app)
            if app.axis == 1
                sliced_data = squeeze(app.data(app.IndexSpinner.Value, :, :));
            elseif app.axis == 2
                sliced_data = squeeze(app.data(:, app.IndexSpinner.Value, :));
            elseif app.axis == 3 
                sliced_data = squeeze(app.data(:, :, app.IndexSpinner.Value));
            end
        end
    
        
        function plotData(app)
            imagesc(app.UIAxes, slice_data(app));
            plotPolygon(app);
        end
        
        function plotPolygon(app)
            app.roi = images.roi.Polygon(app.UIAxes);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            app.data = data;
            app.XYButton.Value = true;
            app.axis = 3;
            app.IndexSpinner.Value = 1;
            assignin('base','app',app);
            plotData(app)
        end

        % Value changed function: IndexSpinner
        function IndexSpinnerValueChanged(app, event)
            if app.IndexSpinner.Value >0
                if app.IndexSpinner.Value <= size(app.data, app.axis)
                    plotData(app)
                end
            end
        end

        % Selection changed function: AxisButtonGroup
        function AxisButtonGroupSelectionChanged(app, event)
            selectedButton = app.AxisButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'XY')
                app.axis = 3;
            elseif strcmp(selectedButton.Value, 'XZ')
                app.axis = 2;
            else
                app.axis = 1;
            end
            plotData(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.IndexSpinnerLabel = uilabel(app.UIFigure);
            app.IndexSpinnerLabel.Text = 'Index';
            app.IndexSpinner = uispinner(app.UIFigure);
            app.IndexSpinner.ValueChangedFcn = createCallbackFcn(app, @IndexSpinnerValueChanged, true);
            app.AxisButtonGroup = uibuttongroup(app.UIFigure);
            app.AxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxisButtonGroupSelectionChanged, true);
            app.AxisButtonGroup.Title = 'Axis';
            app.XYButton = uiradiobutton(app.AxisButtonGroup);
            app.XYButton.Text = 'XY';
            app.XYButton.Value = true;
            app.XZButton = uiradiobutton(app.AxisButtonGroup);
            app.XZButton.Text = 'XZ';
            app.YZButton = uiradiobutton(app.AxisButtonGroup);
            app.YZButton.Text = 'YZ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = createMask(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end